classdef kernel_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KernelSettingsUIFigure         matlab.ui.Figure
        KernelspecificationPanel       matlab.ui.container.Panel
        CovariateEditFieldLabel        matlab.ui.control.Label
        CovariateEditField             matlab.ui.control.EditField
        ChoosekerneltypeDropDownLabel  matlab.ui.control.Label
        ChoosekerneltypeDropDown       matlab.ui.control.DropDown
        UIAxes_top                     matlab.ui.control.UIAxes
        UIAxes_bottom                  matlab.ui.control.UIAxes
        aEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        cEditFieldLabel                matlab.ui.control.Label
        cEditField                     matlab.ui.control.NumericEditField
        NextButton                     matlab.ui.control.Button
        AllowinteractionCheckBox       matlab.ui.control.CheckBox
        SelectedCheckBox               matlab.ui.control.CheckBox
        Switch                         matlab.ui.control.Switch
        UIAxes_ns                      matlab.ui.control.UIAxes
        BacktoMainButton               matlab.ui.control.Button
    end


    properties (Access = public)
        
        % Specify public properties
        kernelArr = {};
        sourceApp = '';
        dataStructArr = struct('name','', 'period','','a',''...
            ,'b','','c','', 'interaction','','selected','');
        varNames = {};
        setFlag = {};
        kernelNameArrApp = {};
        kernelSelectedArr = {};
        nConVar = {};
        nBinVar = {};
        totalCov = 0;
        contArr = [];
        cur = 0;
        list_items_cont = {};
        list_items_bin = {};
        kernelArr_cont = {};
        kernelArr_bin = {};
        minCov = [];
        maxCov = [];
        range_start = 0;
        range_end = 0;
    end


    methods (Access = private)
    
        function priorEvaluate(app, axis, priorStruct)
            switch priorStruct.type
                case 'Log-Gaussian'
                    title(axis, 'Log Gaussian PDF');
                    mu = priorStruct.mu;
                    std = sqrt(priorStruct.s2);
                    x = (0:0.02:10);
                    y = lognpdf(x, mu, std);
                    plot(axis, x, y);
                  
                case 'Sqrt-t'
                    title(axis, 'Square root Student-t PDF');
                    mu = priorStruct.mu;
                    s2 = priorStruct.s2;
                    nu = sqrt(priorStruct.nu);
                    x = -10:0.1:10;
                    y = t_pdf(x,nu, mu, s2);
                    plot(axis, x, y);
                    
                case 't'
                    title(axis, 'Student-t PDF');
                    mu = priorStruct.mu;
                    s2 = priorStruct.s2;
                    nu = priorStruct.nu;
                    x = -10:0.1:10;
                    y = t_pdf(x,nu, mu, s2);
                    plot(axis, x, y);
                    
                case 'Gaussian'
                    title(axis, 'Gaussian PDF');
                    mu = priorStruct.mu;
                    sigma = sqrt(priorStruct.s2);
                    x = -50:50;
                    y = norm_pdf(x, mu, sigma);
                    plot(axis, x, y);
                    
                case 'Gamma'
                    title(axis, 'Gamma PDF');
                    sh = priorStruct.sh;
                    is = priorStruct.is;
                    x = 0:20;
                    y = gam_pdf(x, sh, is);
                    plot(axis, x, y);
                    
                case 'Inverse Chi-squared'
                    title(axis, 'Inverse Chi-squared PDF')
                    s2 = priorStruct.s2;
                    nu = priorStruct.nu;
                    x = 0:0.1:10;
                    y = sinvchi2_pdf(x, nu, s2);
                    plot(axis, x, y);
                    
                case 'Inv-Gamma'
                    title(axis, 'Inverse Gamma PDF')
                    sh = priorStruct.sh;
                    s = priorStruct.s;
                    x = 0:20;
                    y = invgam_pdf(x, sh, s);
                    plot(axis, x, y);
                    
                case 'Inv-t'
                    title(axis, 'Inverse Student-t PDF')
                    mu = priorStruct.mu;
                    s2 = priorStruct.s2;
                    nu = priorStruct.nu;
                    x = -10:0.1:10;
                    y = t_pdf(x,nu, mu, s2);
                    plot(axis, x, y);
                 
                case 'Laplace'
                    title(axis, 'Laplace PDF')
                    mu = priorStruct.mu;
                    s = priorStruct.s;
                    x = -10:0.1:10;
                    y = laplace_pdf(x, mu, s);
                    plot(axis, x, y);
                    
                case 'Log-t'
                    title(axis, 'Log-t PDF');
                    mu = priorStruct.mu;
                    s2 = priorStruct.s2;
                    nu = priorStruct.nu;
                    x = -10:0.1:10;
                    y = t_lpdf(x, nu, mu, s2);
                    plot(axis, x, y);
                    
                otherwise
                    uialert(app.KernelSettingsUIFigure,...
                        'Visualisation Error','Could not visualise prior: ' + priorStruct.type);
                    return     
            end 
        end
        
    end

    methods (Access = public)
    
        function setNextCovariate(app)
            
            % resetting GUI
            app.UIAxes_top.Visible = 'off';
            app.UIAxes_bottom.Visible = 'off';
            app.UIAxes_ns.Visible = 'off';
            covariate = app.varNames{app.cur};
            app.CovariateEditField.Value = covariate;
          
             if (app.contArr(app.cur) == 0)          
                app.ChoosekerneltypeDropDown.Items = app.list_items_cont;
                app.Switch.Value = 'Continuous';
            else
                app.ChoosekerneltypeDropDown.Items = app.list_items_bin;
                app.Switch.Value = 'Discrete';
            end
            app.aEditField.Visible = 'off';
            app.bEditField.Visible = 'off';
            app.cEditField.Visible = 'off';
            app.aEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Visible = 'off';
            app.cEditFieldLabel.Visible = 'off';
            app.aEditField.Value = 0;
            app.bEditField.Value = 0;
            app.cEditField.Value = 0;
            app.AllowinteractionCheckBox.Value = 0;
            app.SelectedCheckBox.Value = 0;
            if (strcmp(covariate, 'id'))
                app.SelectedCheckBox.Value = 1;
                app.SelectedCheckBox.Enable = 'on';
            end
            
            if (app.setFlag(app.cur) == 0)
                dataStruct = 0;
                app.ChoosekerneltypeDropDown.Value = app.list_items_cont{1};
            else
                dataStruct = app.kernelSelectedArr(app.cur);
            end
            
            if (isstruct(dataStruct))
                app.ChoosekerneltypeDropDown.Value = dataStruct.name;
                if strcmp(dataStruct.name, 'Periodic')
                    app.aEditField.Value = dataStruct.period;
                else
                    app.aEditField.Value = dataStruct.a;
                end
                app.bEditField.Value = dataStruct.b;
                app.cEditField.Value = dataStruct.c;
                app.AllowinteractionCheckBox.Value = dataStruct.interaction;
                app.SelectedCheckBox.Value = dataStruct.selected;
            end

            if (app.cur == app.totalCov)
                app.NextButton.Text = 'Done';
                
            end
            ChoosekerneltypeDropDownValueChanged(app, []);
        end       
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceApp, varNames, setFlag, kernelArrApp, kernelNameArrApp, kernelSelectedArr, nConVar, nBinVar, contArr, minCov, maxCov)
            app.sourceApp = sourceApp;
            app.varNames = varNames;
            app.setFlag = setFlag;
            app.kernelArr = kernelArrApp;
            app.kernelNameArrApp = kernelNameArrApp;
            app.kernelSelectedArr = kernelSelectedArr;
            app.nConVar = nConVar;
            app.nBinVar = nBinVar;
            app.totalCov = length(varNames);
            app.cur = 1;
            app.contArr = contArr;
            app.minCov = minCov;
            app.maxCov = maxCov;     
            app.UIAxes_top.Visible = 'off';
            app.UIAxes_bottom.Visible = 'off';
            app.UIAxes_ns.Visible = 'off';
            covariate = varNames{1};
            app.CovariateEditField.Value = covariate;
            
            for i = 1:length(kernelNameArrApp)
                switch kernelNameArrApp{i}
                    case 'se'
                        app.kernelArr_cont{end+1} = app.kernelArr{i};
                        app.list_items_cont{end+1} = 'Squared Exponential';
                    case 'ns'
                        app.kernelArr_cont{end+1} = app.kernelArr{i};
                        app.list_items_cont{end+1} = 'Non-stationery';
                    case 'pe'
                        app.kernelArr_cont{end+1} = app.kernelArr{i};
                        app.list_items_cont{end+1} = 'Periodic';
                    case 'co'
                        app.kernelArr_cont{end+1} = app.kernelArr{i};
                        app.list_items_cont{end+1} = 'Constant';
                    case 'ca'
                        app.kernelArr_bin{end+1} = app.kernelArr{i};
                        app.list_items_bin{end+1} = 'Categorical';
                    case 'bi'
                        app.kernelArr_bin{end+1} = app.kernelArr{i};
                        app.list_items_bin{end+1} = 'Mask/Binary';
                end 
            end
            
            if (app.contArr(1) == 0)          
                app.ChoosekerneltypeDropDown.Items = app.list_items_cont;
                app.Switch.Value = 'Continuous';
            else
                app.ChoosekerneltypeDropDown.Items = app.list_items_bin;
                app.Switch.Value = 'Discrete';
            end
            
            app.aEditField.Visible = 'off';
            app.bEditField.Visible = 'off';
            app.cEditField.Visible = 'off';
            app.aEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Visible = 'off';
            app.cEditFieldLabel.Visible = 'off';
            
            if (app.setFlag(1) == 0)
                dataStruct = 0;
            else
                dataStruct = app.kernelSelectedArr(1);
            end
            if (isstruct(dataStruct))
                app.ChoosekerneltypeDropDown.Value = dataStruct.name;
                if strcmp(dataStruct.name, 'Periodic')
                    app.aEditField.Value = dataStruct.period;
                else
                    app.aEditField.Value = dataStruct.a;
                end
                app.bEditField.Value = dataStruct.b;
                app.cEditField.Value = dataStruct.c;
                app.AllowinteractionCheckBox.Value = dataStruct.interaction;
                app.SelectedCheckBox.Value = dataStruct.selected;
            end

            if (app.cur == app.totalCov)
                app.NextButton.Text = 'Done';
            end
            sourceApp.LonGPUIFigure.Visible = 'off';
            ChoosekerneltypeDropDownValueChanged(app, []);
        end

        % Callback function: ChoosekerneltypeDropDown, 
        % KernelspecificationPanel
        function ChoosekerneltypeDropDownValueChanged(app, event)
            value = app.ChoosekerneltypeDropDown.Value;
            cla(app.UIAxes_top, 'reset');
            cla(app.UIAxes_bottom, 'reset');
            cla(app.UIAxes_ns, 'reset');
            app.aEditField.Visible = 'off';
            app.bEditField.Visible = 'off';
            app.cEditField.Visible = 'off';
            app.aEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Visible = 'off';
            app.cEditFieldLabel.Visible = 'off';
            app.UIAxes_top.Visible = 'off';
            app.UIAxes_bottom.Visible = 'off';
            app.UIAxes_ns.Visible = 'off';
            switch value
                case 'Squared Exponential'
                    % get index
                    idx = find(strcmp(app.list_items_cont, value));
                    % make visible
                    app.UIAxes_top.Visible = 'on';
                    app.UIAxes_bottom.Visible = 'on';
                    
                    % set axis length scale
                    app.UIAxes_top.XLabel.String = 'Length scale';
                    priorEvaluate(app, app.UIAxes_top, app.kernelArr_cont{1,idx}.p.lengthScale);
                    
                    % set axis magnitude
                    app.UIAxes_bottom.XLabel.String = 'Magnitude';
                    priorEvaluate(app, app.UIAxes_bottom, app.kernelArr_cont{1,idx}.p.magnSigma2);
                 
                case 'Non-stationery'
                    app.aEditField.Visible = 'on';
                    app.bEditField.Visible = 'on';
                    app.cEditField.Visible = 'on';
                    app.aEditFieldLabel.Visible = 'on';
                    app.bEditFieldLabel.Visible = 'on';
                    app.cEditFieldLabel.Visible = 'on';
                    app.aEditFieldLabel.Text = 'a';
                    app.bEditFieldLabel.Text = 'b';
                    app.cEditFieldLabel.Text = 'c';
                    
                    % get index
                    idx = find(strcmp(app.list_items_cont, value));
                    % make visible
                    app.UIAxes_top.Visible = 'on';
                    app.UIAxes_bottom.Visible = 'on';
                    app.UIAxes_ns.Visible = 'on';
                    
                    % set axis length scale
                    app.UIAxes_top.XLabel.String = 'Length scale';
                    priorEvaluate(app, app.UIAxes_top, app.kernelArr_cont{1,idx}.p.lengthScale);
                    
                    % set axis magnitude
                    app.UIAxes_bottom.XLabel.String = 'Magnitude';
                    priorEvaluate(app, app.UIAxes_bottom, app.kernelArr_cont{1,idx}.p.magnSigma2);
                    
                    % set axis transformation
                    app.UIAxes_ns.XLabel.String = 'Original data';
                    app.UIAxes_ns.YLabel.String = 'Transformed data';
                    app.range_start = app.minCov(app.cur);
                    app.range_start = app.range_start + 2*app.range_start;
                    app.range_end = app.maxCov(app.cur);
                    app.range_end = app.range_end + 2* app.range_end;
                    abs_max = max([abs(app.minCov(app.cur)), abs(app.maxCov(app.cur))]);
                    if (app.setFlag(app.cur) == 0)
                        % defaults
                        app.aEditField.Value = 0.5;
                        app.bEditField.Value = 0;
                        app.cEditField.Value = abs_max;
                    end
                    a = app.aEditField.Value;
                    b = app.bEditField.Value;
                    c = app.cEditField.Value;
                    x = app.range_start:app.range_end;
                    y = nstran(x, a, b, c);
                    title(app.UIAxes_ns, 'Non-stationery transformation');
                    plot(app.UIAxes_ns, x, y);   
                        
                    
                case 'Periodic'
                    app.aEditField.Visible = 'on';
                    app.aEditFieldLabel.Visible = 'on';
                    app.aEditFieldLabel.Text = 'Period:';
                    
                    % get index
                    idx = find(strcmp(app.list_items_cont, value));
                    % make visible
                    app.UIAxes_top.Visible = 'on';
                    app.UIAxes_bottom.Visible = 'on';
                    
                    % set axis length scale
                    app.UIAxes_top.XLabel.String = 'Length scale';
                    priorEvaluate(app, app.UIAxes_top, app.kernelArr_cont{1,idx}.p.lengthScale);
                    
                    % set axis magnitude
                    app.UIAxes_bottom.XLabel.String = 'Magnitude';
                    priorEvaluate(app, app.UIAxes_bottom, app.kernelArr_cont{1,idx}.p.magnSigma2);
                    
                case 'Constant'
                    % get index
                    idx = find(strcmp(app.list_items_cont, value));
                    % make visible
                    app.UIAxes_top.Visible = 'on';
                    app.UIAxes_bottom.Visible = 'off';
                    
                    % set axis length scale
                    app.UIAxes_top.XLabel.String = 'Constant variance';
                    priorEvaluate(app, app.UIAxes_top, app.kernelArr_cont{1,idx}.p.constSigma2);
                    
                case 'Categorical'
                    app.UIAxes_top.Visible = 'off';
                    app.UIAxes_bottom.Visible = 'off';
                    
                case 'Mask/Binary'
                    app.UIAxes_top.Visible = 'off';
                    app.UIAxes_bottom.Visible = 'off';          
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            dataStruct.name = app.ChoosekerneltypeDropDown.Value;
            if strcmp(dataStruct.name, 'Periodic')
                dataStruct.period = app.aEditField.Value;
                dataStruct.a = 0;
            else
                dataStruct.period = 0;
                dataStruct.a = app.aEditField.Value;
            end
            dataStruct.b = app.bEditField.Value;
            dataStruct.c = app.cEditField.Value;
            dataStruct.interaction = app.AllowinteractionCheckBox.Value;
            dataStruct.selected = app.SelectedCheckBox.Value;
            
            if (strcmp(app.Switch.Value, 'Continuous'))
                app.contArr(app.cur) = 0;
            else
                app.contArr(app.cur) = 1;
            end
            app.kernelSelectedArr(app.cur) = dataStruct;
            app.setFlag(app.cur) = 1;
            
            if (app.cur == app.totalCov)
                % terminate and set kernels
                app.nBinVar = sum(app.contArr);
                app.nConVar = app.totalCov - app.nBinVar;
                % check for binary at end
                last = find(app.contArr==0,1,'last');
                flag = 0;
                if (last == length(app.varNames))
                    flag = 1;
                end
                
                if isempty(last)
                   flag = 2;
                end
                
                for i =1:last      
                    if (app.contArr(i) == 1)
                        flag = 3;
                        break;
                    end
                end
                
                for i = last+1:length(app.varNames)
                    if (app.contArr(i) == 0)
                        flag = 4;
                        break;
                    end
                end
                
                if (~strcmp(app.varNames{app.cur}, 'id'))
                     flag = 5;
                end
                if (flag > 0)
                    kernel_fail(app.sourceApp,flag);
                    delete(app);
                else
                    kernel_window_callback(app.sourceApp, app.kernelSelectedArr,...
                    app.setFlag, app.nConVar, app.nBinVar, app.contArr);
                    delete(app);
                end
            else
                app.cur = app.cur + 1;
                setNextCovariate(app);
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if (strcmp(value,'Continuous'))
                app.contArr(app.cur) = 0;
                app.ChoosekerneltypeDropDown.Items = app.list_items_cont;
            else
                app.contArr(app.cur) = 1;
                app.ChoosekerneltypeDropDown.Items = app.list_items_bin;
            end
            ChoosekerneltypeDropDownValueChanged(app, []);
                
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            value = app.aEditField.Value;
            if ~isempty(value)
                cla(app.UIAxes_ns, 'reset');
                a = app.aEditField.Value;
                b = app.bEditField.Value;
                c = app.cEditField.Value;
                x = app.range_start:app.range_end;
                y = nstran(x, a, b, c);
                title(app.UIAxes_ns, 'Non-stationery transformation');
                app.UIAxes_ns.XLabel.String = 'Original data';
                app.UIAxes_ns.YLabel.String = 'Transformed data';
                plot(app.UIAxes_ns, x, y);   
            end
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            value = app.cEditField.Value;
            if ~isempty(value)
                cla(app.UIAxes_ns, 'reset');
                a = app.aEditField.Value;
                b = app.bEditField.Value;
                c = app.cEditField.Value;
                x = app.range_start:app.range_end;
                y = nstran(x, a, b, c);
                title(app.UIAxes_ns, 'Non-stationery transformation');
                app.UIAxes_ns.XLabel.String = 'Original data';
                app.UIAxes_ns.YLabel.String = 'Transformed data';
                plot(app.UIAxes_ns, x, y);       
            end
        end

        % Value changed function: cEditField
        function cEditFieldValueChanged(app, event)
            value = app.cEditField.Value;
            if ~isempty(value)
                cla(app.UIAxes_ns, 'reset');
                a = app.aEditField.Value;
                b = app.bEditField.Value;
                c = app.cEditField.Value;
                x = app.range_start:app.range_end;
                y = nstran(x, a, b, c);
                title(app.UIAxes_ns, 'Non-stationery transformation');
                app.UIAxes_ns.XLabel.String = 'Original data';
                app.UIAxes_ns.YLabel.String = 'Transformed data';
                plot(app.UIAxes_ns, x, y); 
            end
        end

        % Button pushed function: BacktoMainButton
        function BacktoMainButtonPushed(app, event)
            back_callback(app.sourceApp);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KernelSettingsUIFigure = uifigure;
            app.KernelSettingsUIFigure.Name = 'Kernel Settings';
            app.KernelspecificationPanel = uipanel(app.KernelSettingsUIFigure);
            app.KernelspecificationPanel.Title = 'Kernel specification';
            app.KernelspecificationPanel.SizeChangedFcn = createCallbackFcn(app, @ChoosekerneltypeDropDownValueChanged, true);
            app.CovariateEditFieldLabel = uilabel(app.KernelspecificationPanel);
            app.CovariateEditFieldLabel.Text = 'Covariate:';
            app.CovariateEditField = uieditfield(app.KernelspecificationPanel, 'text');
            app.CovariateEditField.Editable = 'off';
            app.ChoosekerneltypeDropDownLabel = uilabel(app.KernelspecificationPanel);
            app.ChoosekerneltypeDropDownLabel.Text = 'Choose kernel type:';
            app.ChoosekerneltypeDropDown = uidropdown(app.KernelspecificationPanel);
            app.ChoosekerneltypeDropDown.Items = {};
            app.ChoosekerneltypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosekerneltypeDropDownValueChanged, true);
            app.ChoosekerneltypeDropDown.Value = {};
            app.UIAxes_top = uiaxes(app.KernelspecificationPanel);
            title(app.UIAxes_top, 'Title')
            xlabel(app.UIAxes_top, 'X')
            ylabel(app.UIAxes_top, 'Y')
            app.UIAxes_bottom = uiaxes(app.KernelspecificationPanel);
            title(app.UIAxes_bottom, 'Title')
            xlabel(app.UIAxes_bottom, 'X')
            ylabel(app.UIAxes_bottom, 'Y')
            app.aEditFieldLabel = uilabel(app.KernelspecificationPanel);
            app.aEditFieldLabel.Text = 'a:';
            app.aEditField = uieditfield(app.KernelspecificationPanel, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.bEditFieldLabel = uilabel(app.KernelspecificationPanel);
            app.bEditFieldLabel.Text = 'b:';
            app.bEditField = uieditfield(app.KernelspecificationPanel, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.cEditFieldLabel = uilabel(app.KernelspecificationPanel);
            app.cEditFieldLabel.Text = 'c:';
            app.cEditField = uieditfield(app.KernelspecificationPanel, 'numeric');
            app.cEditField.ValueChangedFcn = createCallbackFcn(app, @cEditFieldValueChanged, true);
            app.NextButton = uibutton(app.KernelspecificationPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.AllowinteractionCheckBox = uicheckbox(app.KernelspecificationPanel);
            app.AllowinteractionCheckBox.Text = '  Allow interaction';
            app.SelectedCheckBox = uicheckbox(app.KernelspecificationPanel);
            app.SelectedCheckBox.Text = '  Selected';
            app.Switch = uiswitch(app.KernelspecificationPanel, 'slider');
            app.Switch.Items = {'Continuous', 'Discrete'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Continuous';
            app.UIAxes_ns = uiaxes(app.KernelspecificationPanel);
            title(app.UIAxes_ns, 'Title')
            xlabel(app.UIAxes_ns, 'X')
            ylabel(app.UIAxes_ns, 'Y')
            app.BacktoMainButton = uibutton(app.KernelspecificationPanel, 'push');
            app.BacktoMainButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoMainButtonPushed, true);
            app.BacktoMainButton.Text = 'Back to Main';
        end
    end

    methods (Access = public)

        % Construct app
        function app = kernel_dialog(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KernelSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KernelSettingsUIFigure)
        end
    end
end