classdef plot_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotsettingsUIFigure          matlab.ui.Figure
        FiguregenerationoptionsPanel  matlab.ui.container.Panel
        GeneratefiguresCheckBox       matlab.ui.control.CheckBox
        CovariateindexforxaxisEditFieldLabel  matlab.ui.control.Label
        CovariateindexforxaxisEditField  matlab.ui.control.EditField
        CovariateindexforcolouringEditFieldLabel  matlab.ui.control.Label
        CovariateindexforcolouringEditField  matlab.ui.control.EditField
        DoneButton                    matlab.ui.control.Button
        BackButton                    matlab.ui.control.Button
    end


    properties (Access = public)
        
        % Specify public properties
        idxXAxis = 0;  
        idxColour = 0;
        genFig = 0;
        sourceApp = '';
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceApp, idxXAxis, idxColour, genFig)
            app.sourceApp = sourceApp;
            app.idxXAxis = idxXAxis;
            app.idxColour = idxColour;
            app.genFig = genFig;
            
            if (app.genFig == 1)
                app.GeneratefiguresCheckBox.Value = 1;
                app.CovariateindexforxaxisEditField.Enable = 'on';
                app.CovariateindexforcolouringEditField.Enable = 'on';
                app.CovariateindexforxaxisEditFieldLabel.Enable = 'on';
                app.CovariateindexforcolouringEditFieldLabel.Enable = 'on';
                app.CovariateindexforcolouringEditField.Value = num2str(app.idxColour);
                app.CovariateindexforxaxisEditField.Value = num2str(app.idxXAxis);
            else
                app.GeneratefiguresCheckBox.Value = 0;
            end

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.idxColour = app.CovariateindexforcolouringEditField.Value;
            app.idxXAxis = app.CovariateindexforxaxisEditField.Value;
            app.genFig = app.GeneratefiguresCheckBox.Value;
            flag = 0;
            
            if (app.GeneratefiguresCheckBox.Value == 1)
               [~, status] = str2num(app.idxXAxis);
               if(strcmp(app.idxXAxis, '') || status == 0 )
                   app.CovariateindexforxaxisEditFieldLabel.FontColor = 'red';
                   flag = 1;
               end  
               
               [~, status] = str2num(app.idxColour);
               if(strcmp(app.idxColour, '') || status == 0 )
                   app.CovariateindexforcolouringEditFieldLabel.FontColor = 'red';
                   flag = 1;
               end    
            end
            
           if (flag == 1)
                uialert(app.PlotsettingsUIFigure,'Please fill and verify all mandatory fields.','Incomplete specification');
                return;
           end
            
           image_window_callback(app.sourceApp, app.idxXAxis, app.idxColour, app.genFig);       
           delete(app);       
        end

        % Value changed function: GeneratefiguresCheckBox
        function GeneratefiguresCheckBoxValueChanged(app, event)
            value = app.GeneratefiguresCheckBox.Value;
            if (value == 1)
                app.CovariateindexforxaxisEditField.Enable = 'on';
                app.CovariateindexforcolouringEditField.Enable = 'on';
                app.CovariateindexforxaxisEditFieldLabel.Enable = 'on';
                app.CovariateindexforcolouringEditFieldLabel.Enable = 'on';
            else
                app.CovariateindexforxaxisEditField.Enable = 'off';
                app.CovariateindexforcolouringEditField.Enable = 'off';
                app.CovariateindexforxaxisEditFieldLabel.Enable = 'off';
                app.CovariateindexforcolouringEditFieldLabel.Enable = 'off';
                app.CovariateindexforcolouringEditField.Value = '';
                app.CovariateindexforxaxisEditField.Value = '';
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            back_callback(app.sourceApp);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlotsettingsUIFigure = uifigure;
            app.PlotsettingsUIFigure.Name = 'Plot settings';
            app.FiguregenerationoptionsPanel = uipanel(app.PlotsettingsUIFigure);
            app.FiguregenerationoptionsPanel.Title = 'Figure generation options';
            app.GeneratefiguresCheckBox = uicheckbox(app.FiguregenerationoptionsPanel);
            app.GeneratefiguresCheckBox.ValueChangedFcn = createCallbackFcn(app, @GeneratefiguresCheckBoxValueChanged, true);
            app.GeneratefiguresCheckBox.Text = '  Generate figures';
            app.CovariateindexforxaxisEditFieldLabel = uilabel(app.FiguregenerationoptionsPanel);
            app.CovariateindexforxaxisEditFieldLabel.Enable = 'off';
            app.CovariateindexforxaxisEditFieldLabel.Text = {'Covariate index'; 'for x axis:'};
            app.CovariateindexforxaxisEditField = uieditfield(app.FiguregenerationoptionsPanel, 'text');
            app.CovariateindexforxaxisEditField.Enable = 'off';
            app.CovariateindexforcolouringEditFieldLabel = uilabel(app.FiguregenerationoptionsPanel);
            app.CovariateindexforcolouringEditFieldLabel.Enable = 'off';
            app.CovariateindexforcolouringEditFieldLabel.Text = {'Covariate index'; 'for colouring:'};
            app.CovariateindexforcolouringEditField = uieditfield(app.FiguregenerationoptionsPanel, 'text');
            app.CovariateindexforcolouringEditField.Enable = 'off';
            app.DoneButton = uibutton(app.PlotsettingsUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.BackButton = uibutton(app.PlotsettingsUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
        end
    end

    methods (Access = public)

        % Construct app
        function app = plot_window(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotsettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotsettingsUIFigure)
        end
    end
end